classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % UI Figure
        Panel                 matlab.ui.container.Panel          % load pannel
        tTHlongLoad           matlab.ui.control.Button           % Load tTH...
        tTHshortLoad          matlab.ui.control.Button           % Load tTH...
        Label                 matlab.ui.control.Label            % Label
        Label2                matlab.ui.control.Label            % Label
        LabelDropDown         matlab.ui.control.Label            % Signals ...
        signalsList1          matlab.ui.control.DropDown         % Option 1...
        Label3                matlab.ui.control.Label            % Signals ...
        signalsList2          matlab.ui.control.DropDown         % Option 1...
        Panel2                matlab.ui.container.Panel          % Resample
        LabelEditField        matlab.ui.control.Label            % dt
        resTime               matlab.ui.control.EditField        % 0.01
        resampleBtt           matlab.ui.control.Button           % Resample
        LampRes               matlab.ui.control.Lamp            
        Panel3                matlab.ui.container.Panel          % Sincro
        sincroBtt             matlab.ui.control.Button           % Synchron...
        LabelNumericEditField matlab.ui.control.Label            % time
        timeAdd               matlab.ui.control.NumericEditField % [-Inf Inf]
        LampSincro            matlab.ui.control.Lamp            
        slowEvalBox           matlab.ui.control.CheckBox         % split ev...
        Save                  matlab.ui.control.Button           % Save
        UIAxes                matlab.ui.control.UIAxes           % Title
        Label4                matlab.ui.control.Label            % prefix tTH2
        prfLab2               matlab.ui.control.EditField        % CAN_
        LabelDropDown2        matlab.ui.control.Label            % Save option
        uidropdown            matlab.ui.control.DropDown         % save rep...
        extImgBtt             matlab.ui.control.Button          
        Label5                matlab.ui.control.Label            % Common s...
        cmChannelList         matlab.ui.control.DropDown         % Option 1...
        updatePlotBtt         matlab.ui.control.Button          
    end

    
    properties (Access = private)
        tTH1;       % Struct acq 1
        len1        % lunghezza dei segnali di tTH1
        step1;      % step temporale dell'acq.1
        file1;      % nome dir e file dell'acq 1
        sinkName1;  % nome del segnale di riferimento sun cui calcolare la sincronizzazione
        
        tTH2;       % Struct acq 2
        len2;       % lunghezza dei segnali di tTH2
        step2;      % step temporale dell'acq.2
        file2;      % nome dir e file dell'acq 2
        sinkName2;  % nome del segnale di riferimento sun cui calcolare la sincronizzazione
        
        % sncFlag;
        idMin;      % id signalL in cui inizia signalS
        timeMin;    % tempo a cui è riferito idMin tTH1.time.v(idMin)
        
        extFig;     % external figure obj
        extSp;      % subplot estero di extFig
    end
    
    methods (Access = private)
        
        function results = updatePlot(app)
            cla(app.UiAxes)
            plot(app.UIAxes, app.tTH1.time.v, app.tTH1.(app.sinkName1).v); hold on;
            plot(app.UIAxes, app.tTH2.time.v, app.tTH2.(app.sinkName2).v);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.file1.dir = '.\';
            app.file2.dir = '.\';
            app.extImgBtt.Icon = '.\ico\export.jpg';
            app.updatePlotBtt.Icon = '.\ico\update.png';
%             app.sncFlag = 0;
            % creo un obj figure
            app.extFig = figure();
            delete(app.extFig)
            %             [tb,btns] = axtoolbar(app.UIAxes,{'zoomin','zoomout','restoreview'});
            
        end

        % tTHlongLoad button pushed function
        function tTHlongLoadButtonPushed(app)
            % caricamento traccia lunga
            [app.file1.name, app.file1.dir] = uigetfile(strcat(app.file1.dir,'*.mat'),'trace 1 (long trace)');
            app.UIFigure.Visible = 'off'; app.UIFigure.Visible = 'on'; % serve per far tornare in primo piano la figura principale
            app.tTH1  = load(strcat(app.file1.dir,app.file1.name)); app.tTH1 = app.tTH1.tTH; % carico acq.1
            app.len1  = length(app.tTH1.time.v);
            app.step1 = app.tTH1.time.v(2) -app.tTH1.time.v(1);
            %aggiorno la label con le informazioni utili
            s1 = sprintf('length : %d;', app.len1);
            s2 = sprintf('step   : %.3f s',app.step1);
            app.Label.Text = {app.file1.name; s1; s2} ;
            % aggiorno la list box dei nomi dei segnali
            app.signalsList1.Items = fieldnames(app.tTH1);
            % se ho già caricato tTH2 cerco i nomi che coincidono nelle due
            % acq e popolo la commom channel list.
            if isstruct(app.tTH2)
                names1 = fieldnames(app.tTH1);
                names2 = fieldnames(app.tTH2);
                listName = {};
                for i=1:length(names2)
                    name = names2{i};
                    if any(strcmp(names1, name))
                        listName{end+1} = name;
                    end
                end
                app.cmChannelList.Items = listName;
            end
        end

        % tTHshortLoad button pushed function
        function tTHshortLoadButtonPushed(app)
            % caricamento l timehistory corta
            [app.file2.name, app.file2.dir] = uigetfile(strcat(app.file2.dir,'*.mat'),'trace 1 (long trace)');
            app.UIFigure.Visible = 'off'; app.UIFigure.Visible = 'on';  % serve per far tornare in primo piano la figura principale
            app.tTH2  = load(strcat(app.file2.dir,app.file2.name)); app.tTH2 = app.tTH2.tTH;
            app.len2  = length(app.tTH2.time.v);
            app.step2 = app.tTH2.time.v(2) -app.tTH2.time.v(1);
            %aggiorno la label con le informazioni util
            s1 = sprintf('length : %d;', app.len2);
            s2 = sprintf('step   : %.3f s',app.step2);
            app.Label2.Text = {app.file2.name; s1; s2} ;
            % aggiorno la list box dei nomi dei segnali
            app.signalsList2.Items = fieldnames(app.tTH2);
            % se ho già caricato tTH1 cerco i nomi che coincidono nelle due
            % acq e popolo la commom channel list.
            if isstruct(app.tTH1)
                names1 = fieldnames(app.tTH1);
                names2 = fieldnames(app.tTH2);
                listName = {};
                for i=1:length(names2)
                    name = names2{i};
                    if any(strcmp(names1, name))
                        listName{end+1} = name;
                    end
                end
                app.cmChannelList.Items = listName;
            end
            if app.tTH2.time.v(1)<0
                app.tTH2.time.v = app.tTH2.time.v-app.tTH2.time.v(1);
            end
        end

        % signalsList1 value changed function
        function signalsList1ValueChanged(app, event)
            % selezione del nome del segnale da sincronizzare
            value = app.signalsList1.Value;
            app.sinkName1 = value;
%             app.sncFlag = 0;
        end

        % signalsList2 value changed function
        function signalsList2ValueChanged(app, event)
            % selezione del nome del segnale da sincronizzare
            value = app.signalsList2.Value;
            app.sinkName2 = value;
%             app.sncFlag = 0;
        end

        % resampleBtt button pushed function
        function resampleBttButtonPushed(app)
            app.LampRes.Color = [1,0,0];
            step = str2num(app.resTime.Value);
            oldTime = app.tTH1.time.v;
            names = fieldnames(app.tTH1);
            timeSpace = oldTime(1):step: oldTime(end);
            for i=1:length(names)
                app.tTH1.(names{i}).v = interp1(oldTime, app.tTH1.(names{i}).v, timeSpace);
            end
            
            oldTime = app.tTH2.time.v;
            names = fieldnames(app.tTH2);
            timeSpace = oldTime(1):step: oldTime(end);
            %             figure();
            %     plot(app.tTH2.time.v, app.tTH2.EngineSpeed.v, '+k'); hold on;
            for i=1:length(names)
                app.tTH2.(names{i}).v = interp1(oldTime, app.tTH2.(names{i}).v, timeSpace);
            end
            %             plot(app.tTH2.time.v, app.tTH2.EngineSpeed.v, 'og');
            app.LampRes.Color = [0,1,0];
        end

        % sincroBtt button pushed function
        function sincroBttButtonPushed(app)
            app.LampSincro.Color = [1,0,0];
%             app.sncFlag=1;
            assignin('base', 'app', app)
            disp(app.slowEvalBox.Value)
            
            if app.slowEvalBox.Value
                %                 app.idMin = slow_sink(app.tTH1.(app.sinkName1).v, app.tTH2.(app.sinkName2).v);
                app.idMin = cerca_sink1_3(app.tTH1.(app.sinkName1).v, app.tTH2.(app.sinkName2).v);
            else
                app.idMin = cerca_sinkID(app.tTH1.(app.sinkName1).v, app.tTH2.(app.sinkName2).v);
                app.idMin = verify_sink(app.idMin, app.tTH1.(app.sinkName1).v,  app.tTH2.(app.sinkName2).v);
            end
            
            [app.tTH1, app.tTH2, app.idMin] = update_tTH_space(app.idMin, app.tTH1, app.tTH2);
            
            %             disp(app.idMin)
            app.timeAdd.Value = app.tTH1.time.v(app.idMin);
            app.updatePlotBttButtonPushed()
            app.LampSincro.Color = [0,1,0];
        end

        % updatePlotBtt button pushed function
        function updatePlotBttButtonPushed(app)
            app.LampSincro.Color = [1,0,0];
            if isvalid(app.extFig)
                app.extImgBttButtonPushed();
            end
            ax = app.UIAxes;
            xlabel(ax, 'Time [s]');
            ylabel(ax, strcat(app.sinkName1, ' [', app.tTH1.(app.sinkName1).u,']'));
            cla(ax, 'reset');
            hold(ax,'on');
            plot(ax, app.tTH1.time.v, app.tTH1.(app.sinkName1).v, 'DisplayName', 'tTH1 source');
            plot(ax, app.tTH2.time.v, app.tTH2.(app.sinkName2).v, 'DisplayName', 'tTH2 source');
            plot(ax, app.tTH2.time.v + app.timeAdd.Value , app.tTH2.(app.sinkName2).v, '--', 'DisplayName', 'tTH1 source');
            grid(ax, 'minor');
            %             legend(ax, {'tTH1 source', 'tTH2 source', 'tTH2 sinked'});
            app.LampSincro.Color = [0,1,0];
        end

        % Save button pushed function
        function SaveButtonPushed(app)
            risp = ceil(str2double(app.uidropdown.Value));
            idMin = app.idMin;
            switch risp
                case 1
                    prefix2 = app.prfLab2.Value;
                    names1 = fieldnames(app.tTH1);
                    names2 = fieldnames(app.tTH2);
                    tTHtmp = app.tTH2;
                    for i = 1:length(names2)
                        name = names2{i};
                        if any(strcmp(names1, name))
                            name2 = strcat(prefix2, name);
                            tTHtmp.(name2) = tTHtmp.(name);
                            tTHtmp = rmfield(tTHtmp,name);
                        end
                    end
                    tTH = app.tTH1;
                    names1 = fieldnames(app.tTH1);
                    names2 = fieldnames(tTHtmp);
                    for i = 1:length(names2)
                        name = names2{i};
                        v = zeros(size(tTH.(names1{1}).v));
                        v(idMin:idMin+length(tTHtmp.(name).v)-1) = tTHtmp.(name).v;
                        tTH.(name) = tTHtmp.(name);
                        tTH.(name).v = v;
                    end
                case 2
                    tTHtmp = app.tTH2;
                    tTH = app.tTH1;
                    names1 = fieldnames(app.tTH1);
                    names2 = fieldnames(tTHtmp);
                    for i = 1:length(names2)
                        name = names2{i};
                        if any(strcmp(names1, name))
                            if ~strcmp(name, 'time')
                                tTH.(name).v(idMin:idMin+length(tTHtmp.(name).v)-1) = tTHtmp.(name).v;
                            end
                        else
                            v = zeros(size(tTH.(names1{1}).v));
                            v(idMin:idMin+length(tTHtmp.(name).v)-1) = tTHtmp.(name).v;
                            tTH.(name) = tTHtmp.(name);
                            tTH.(name).v = v;
                        end
                    end
            end
            uisave('tTH','marged');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
        end

        % timeAdd value changed function
        function timeAddValueChanged(app)
            value = app.timeAdd.Value;
            [~, app.idMin] = min(abs(app.tTH1.time.v-value));
        end

        % zoomBtt value changed function
        function zoomBttValueChanged(app)
            value = app.zoomBtt.Value;
            if value
                assignin('base','app', app)
            else
                zoom(app.UIAxes,'off');
            end
        end

        % extImgBtt button pushed function
        function extImgBttButtonPushed(app)
            app.LampSincro.Color = [1,0,0];
            if ~isvalid(app.extFig)
                app.extFig = figure();
                app.extSp = subplot(1,1,1);
            end
            ax = app.extSp;
            cla(ax);
            xlabel(ax, 'Time [s]');
            ylabel(ax, strcat(app.sinkName1, ' [', app.tTH1.(app.sinkName1).u,']'));
            cla(ax, 'reset');
            hold(ax,'on');
            plot(ax, app.tTH1.time.v, app.tTH1.(app.sinkName1).v, 'LineWidth',2, 'DisplayName', 'tTH1 source');
            plot(ax, app.tTH2.time.v, app.tTH2.(app.sinkName2).v, 'DisplayName', 'tTH2 source');
            plot(ax, app.tTH2.time.v + app.timeAdd.Value, app.tTH2.(app.sinkName2).v, '--',                  'LineWidth',2, 'DisplayName', 'tTH2 Translated');
            grid(ax, 'minor');
            legend(ax); % {'tTH1 source', 'tTH2 translated'}
            app.LampSincro.Color = [0,1,0];
        end

        % cmChannelList value changed function
        function cmChannelListValueChanged(app, event)
            value = app.cmChannelList.Value;
            app.signalsList1.Value = value;
            app.signalsList2.Value = value;
            app.sinkName1 = value;
            app.sinkName2 = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'load pannel';
            app.Panel.Units = 'pixels';
            app.tTHlongLoad = uibutton(app.Panel, 'push');
            app.tTHlongLoad.ButtonPushedFcn = createCallbackFcn(app, @tTHlongLoadButtonPushed);
            app.tTHlongLoad.Text = 'Load tTH long';
            app.tTHshortLoad = uibutton(app.Panel, 'push');
            app.tTHshortLoad.ButtonPushedFcn = createCallbackFcn(app, @tTHshortLoadButtonPushed);
            app.tTHshortLoad.Text = 'Load tTH short';
            app.Label = uilabel(app.Panel);
            app.Label2 = uilabel(app.Panel);
            app.LabelDropDown = uilabel(app.Panel);
            app.LabelDropDown.Text = 'Signals List';
            app.signalsList1 = uidropdown(app.Panel);
            app.signalsList1.Editable = 'on';
            app.signalsList1.ValueChangedFcn = createCallbackFcn(app, @signalsList1ValueChanged, true);
            app.Label3 = uilabel(app.Panel);
            app.Label3.Text = 'Signals List';
            app.signalsList2 = uidropdown(app.Panel);
            app.signalsList2.Editable = 'on';
            app.signalsList2.ValueChangedFcn = createCallbackFcn(app, @signalsList2ValueChanged, true);
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Resample';
            app.Panel2.Units = 'pixels';
            app.LabelEditField = uilabel(app.Panel2);
            app.LabelEditField.Text = 'dt';
            app.resTime = uieditfield(app.Panel2, 'text');
            app.resTime.Value = '0.01';
            app.resampleBtt = uibutton(app.Panel2, 'push');
            app.resampleBtt.ButtonPushedFcn = createCallbackFcn(app, @resampleBttButtonPushed);
            app.resampleBtt.Text = 'Resample';
            app.LampRes = uilamp(app.Panel2);
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Sincro';
            app.Panel3.Units = 'pixels';
            app.sincroBtt = uibutton(app.Panel3, 'push');
            app.sincroBtt.ButtonPushedFcn = createCallbackFcn(app, @sincroBttButtonPushed);
            app.sincroBtt.Text = 'Synchronization';
            app.LabelNumericEditField = uilabel(app.Panel3);
            app.LabelNumericEditField.Text = 'time';
            app.timeAdd = uieditfield(app.Panel3, 'numeric');
            app.timeAdd.ValueChangedFcn = createCallbackFcn(app, @timeAddValueChanged);
            app.LampSincro = uilamp(app.Panel3);
            app.slowEvalBox = uicheckbox(app.Panel3);
            app.slowEvalBox.Text = 'split evaluation';
            app.Save = uibutton(app.UIFigure, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed);
            app.Save.Text = 'Save';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'prefix tTH2';
            app.prfLab2 = uieditfield(app.UIFigure, 'text');
            app.prfLab2.Value = 'CAN_';
            app.LabelDropDown2 = uilabel(app.UIFigure);
            app.LabelDropDown2.Text = 'Save option';
            app.uidropdown = uidropdown(app.UIFigure);
            app.uidropdown.Items = {'save repeated signals with prefix', 'overwrite tTH2 on tTH1'};
            app.uidropdown.ItemsData = {'1', '2'};
            app.uidropdown.Value = '1';
            app.extImgBtt = uibutton(app.UIFigure, 'push');
            app.extImgBtt.ButtonPushedFcn = createCallbackFcn(app, @extImgBttButtonPushed);
            app.extImgBtt.Text = '';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = 'Common signals';
            app.cmChannelList = uidropdown(app.UIFigure);
            app.cmChannelList.Editable = 'on';
            app.cmChannelList.ValueChangedFcn = createCallbackFcn(app, @cmChannelListValueChanged, true);
            app.updatePlotBtt = uibutton(app.UIFigure, 'push');
            app.updatePlotBtt.ButtonPushedFcn = createCallbackFcn(app, @updatePlotBttButtonPushed);
            app.updatePlotBtt.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end