classdef main1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % UI Figure
        Panel                 matlab.ui.container.Panel          % load pannel
        tTHlongLoad           matlab.ui.control.Button           % Load tTH...
        tTHshortLoad          matlab.ui.control.Button           % Load tTH...
        Label                 matlab.ui.control.Label            % Label
        Label2                matlab.ui.control.Label            % Label
        LabelDropDown         matlab.ui.control.Label            % Signals ...
        signalsList1          matlab.ui.control.DropDown         % Option 1...
        Label3                matlab.ui.control.Label            % Signals ...
        signalsList2          matlab.ui.control.DropDown         % Option 1...
        Panel2                matlab.ui.container.Panel          % Resample
        LabelEditField        matlab.ui.control.Label            % dt
        resTime               matlab.ui.control.EditField        % 0.05
        resampleBtt           matlab.ui.control.Button           % Resample
        Panel3                matlab.ui.container.Panel          % Sincro
        sincroBtt             matlab.ui.control.Button           % Sincronize
        LabelNumericEditField matlab.ui.control.Label            % time
        timeAdd               matlab.ui.control.NumericEditField % [-Inf Inf]
        updatePlotBtt         matlab.ui.control.Button           % Update plot
        Save                  matlab.ui.control.Button           % Save
        UIAxes                matlab.ui.control.UIAxes           % Title
        Label4                matlab.ui.control.Label            % prefix tTH2
        prfLab2               matlab.ui.control.EditField        % CAN_
        LabelDropDown2        matlab.ui.control.Label            % Save option
        uidropdown            matlab.ui.control.DropDown         % save rep...
    end


    properties (Access = private)
        tTH1;  % Struct tTH1
        len1;
        step1;
        file1;
        sinkName1;
        
        tTH2;  % Struct tTH2
        len2;
        step2;
        file2;
        sinkName2;
        
        sncFlag;
        idMin;
        timeMin;       
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.file1.dir = 'C:\Users\matteo.demarco\Desktop\perfo\Test\965_PHEV\WE582\Acq Pizzamiglio\';   
            app.file2.dir = 'C:\Users\matteo.demarco\Desktop\perfo\Test\965_PHEV\WE582\Acq Pizzamiglio\';  
            
            app.sncFlag = 0;
            
%             app.uidropdown.Items = {'save repeated signals with prefix', 'overwrite tTH2 on tTH1', 'overwrite tTH1 on tTH2', 'delete tTH2 repeated signals'};
%             app.uidropdown.Position = [88 41 418 20];
%             app.uidropdown.Value = 'save repeated signals with prefix';
        end

        % tTHlongLoad button pushed function
        function tTHlongLoadButtonPushed(app)
            [app.file1.name, app.file1.dir] = uigetfile(strcat(app.file1.dir,'*.mat'),'trace 1 (long trace)');
            app.tTH1  = load(strcat(app.file1.dir,app.file1.name)); app.tTH1 = app.tTH1.tTH;
            app.len1  = length(app.tTH1.time.v);
            app.step1 = app.tTH1.time.v(2) -app.tTH1.time.v(1);
            s1 = sprintf('length : %d;', app.len1);
            s2 = sprintf('step   : %.3f s',app.step1);
            app.Label.Text = {app.file1.name; s1; s2} ;
            app.signalsList1.Items = fieldnames(app.tTH1);
            
        end

        % tTHshortLoad button pushed function
        function tTHshortLoadButtonPushed(app)
            [app.file2.name, app.file2.dir] = uigetfile(strcat(app.file2.dir,'*.mat'),'trace 1 (long trace)');
            app.tTH2  = load(strcat(app.file2.dir,app.file2.name)); app.tTH2 = app.tTH2.tTH;
            app.len2  = length(app.tTH2.time.v);
            app.step2 = app.tTH2.time.v(2) -app.tTH2.time.v(1);
            s1 = sprintf('length : %d;', app.len2);
            s2 = sprintf('step   : %.3f s',app.step2);
            app.Label2.Text = {app.file2.name; s1; s2} ;     
            app.signalsList2.Items = fieldnames(app.tTH2);
        end

        % signalsList1 value changed function
        function signalsList1ValueChanged(app, event)
            value = app.signalsList1.Value;
            app.sinkName1 = value;
            app.sncFlag = 0;
        end

        % signalsList2 value changed function
        function signalsList2ValueChanged(app, event)
            value = app.signalsList2.Value;
            app.sinkName2 = value;
            app.sncFlag = 0;
        end

        % resampleBtt button pushed function
        function resampleBttButtonPushed(app)

            step = str2num(app.resTime.Value);
            oldTime = app.tTH1.time.v;
            names = fieldnames(app.tTH1);
            timeSpace = oldTime(1):step: oldTime(end);
            for i=1:length(names)
                app.tTH1.(names{i}).v = interp1(oldTime, app.tTH1.(names{i}).v, timeSpace);
            end   
            
            oldTime = app.tTH2.time.v;
            names = fieldnames(app.tTH2);
            timeSpace = oldTime(1):step: oldTime(end);
            for i=1:length(names)
                app.tTH2.(names{i}).v = interp1(oldTime, app.tTH2.(names{i}).v, timeSpace);
            end

        end

        % sincroBtt button pushed function
        function sincroBttButtonPushed(app)

            app.sncFlag=1; 
            assignin('base', 'app', app)
            app.idMin = cerca_sinkID(app.tTH1.(app.sinkName1).v, app.tTH2.(app.sinkName2).v);
            disp(app.idMin)
            app.timeAdd.Value = app.tTH1.time.v(app.idMin);
            app.updatePlotBttButtonPushed()

        end

        % updatePlotBtt button pushed function
        function updatePlotBttButtonPushed(app)

            ax = app.UIAxes;
            xlabel(ax, 'Time [s]');
            ylabel(ax, strcat(app.sinkName1, ' [', app.tTH1.(app.sinkName1).u,']'));
            cla(ax, 'reset');
            hold(ax,'on');
            plot(ax, app.tTH1.time.v, app.tTH1.(app.sinkName1).v, 'DisplayName', 'tTH1 source'); 
            plot(ax, app.tTH2.time.v, app.tTH2.(app.sinkName2).v, 'DisplayName', 'tTH2 source');      
            plot(ax, app.tTH2.time.v + app.timeAdd.Value - app.tTH2.time.v(1), app.tTH2.(app.sinkName2).v, 'DisplayName', 'tTH1 source'); 
            grid(ax, 'minor');
%             legend(ax, {'tTH1 source', 'tTH2 source', 'tTH2 sinked'});

        end

        % Save button pushed function
        function SaveButtonPushed(app)
            risp = ceil(str2double(app.uidropdown.Value));
            idMin = app.idMin;  
            switch risp
                case 1
                    prefix2 = app.prfLab2.Value;
                    names1 = fieldnames(app.tTH1); 
                    names2 = fieldnames(app.tTH2);
                    tTHtmp = app.tTH2;
                    for i = 1:length(names2)
                        name = names2{i};
                        if any(strcmp(names1, name))
                            name2 = strcat(prefix2, name);
                            tTHtmp.(name2) = tTHtmp.(name);
                            tTHtmp = rmfield(tTHtmp,name);
                        end
                    end
                    tTH = app.tTH1;
                    names1 = fieldnames(app.tTH1); 
                    names2 = fieldnames(tTHtmp);
                    for i = 1:length(names2)
                        name = names2{i};
                        v = zeros(size(tTH.(names1{1}).v));
                        v(idMin:idMin+length(tTHtmp.(name).v)-1) = tTHtmp.(name).v;
                        tTH.(name) = tTHtmp.(name);
                        tTH.(name).v = v;
                    end
                case 2 
                    tTHtmp = app.tTH2;
                    tTH = app.tTH1;
                    names1 = fieldnames(app.tTH1); 
                    names2 = fieldnames(tTHtmp);
                    for i = 1:length(names2)
                        name = names2{i};
                        if any(strcmp(names1, name))
                            tTH.(name).v(idMin:idMin+length(tTHtmp.(name).v)-1) = tTHtmp.(name).v;
                        else
                            v = zeros(size(tTH.(names1{1}).v));
                            v(idMin:idMin+length(tTHtmp.(name).v)-1) = tTHtmp.(name).v;
                            tTH.(name) = tTHtmp.(name);
                            tTH.(name).v = v;
                    end 
            end
            uisave('tTH','marged');
    
        end

        % timeAdd value changed function
        function timeAddValueChanged(app)
            value = app.timeAdd.Value;
            [~, app.idMin] = min(abs(app.tTH1.time.v-value));    
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'load pannel';
            app.Panel.Units = 'pixels';
            app.tTHlongLoad = uibutton(app.Panel, 'push');
            app.tTHlongLoad.ButtonPushedFcn = createCallbackFcn(app, @tTHlongLoadButtonPushed);
            app.tTHlongLoad.Text = 'Load tTH long';
            app.tTHshortLoad = uibutton(app.Panel, 'push');
            app.tTHshortLoad.ButtonPushedFcn = createCallbackFcn(app, @tTHshortLoadButtonPushed);
            app.tTHshortLoad.Text = 'Load tTH short';
            app.Label = uilabel(app.Panel);
            app.Label2 = uilabel(app.Panel);
            app.LabelDropDown = uilabel(app.Panel);
            app.LabelDropDown.Text = 'Signals List';
            app.signalsList1 = uidropdown(app.Panel);
            app.signalsList1.Editable = 'on';
            app.signalsList1.ValueChangedFcn = createCallbackFcn(app, @signalsList1ValueChanged, true);
            app.Label3 = uilabel(app.Panel);
            app.Label3.Text = 'Signals List';
            app.signalsList2 = uidropdown(app.Panel);
            app.signalsList2.Editable = 'on';
            app.signalsList2.ValueChangedFcn = createCallbackFcn(app, @signalsList2ValueChanged, true);
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Resample';
            app.Panel2.Units = 'pixels';
            app.LabelEditField = uilabel(app.Panel2);
            app.LabelEditField.Text = 'dt';
            app.resTime = uieditfield(app.Panel2, 'text');
            app.resTime.Value = '0.05';
            app.resampleBtt = uibutton(app.Panel2, 'push');
            app.resampleBtt.ButtonPushedFcn = createCallbackFcn(app, @resampleBttButtonPushed);
            app.resampleBtt.Text = 'Resample';
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Sincro';
            app.Panel3.Units = 'pixels';
            app.sincroBtt = uibutton(app.Panel3, 'push');
            app.sincroBtt.ButtonPushedFcn = createCallbackFcn(app, @sincroBttButtonPushed);
            app.sincroBtt.Text = 'Sincronize';
            app.LabelNumericEditField = uilabel(app.Panel3);
            app.LabelNumericEditField.Text = 'time';
            app.timeAdd = uieditfield(app.Panel3, 'numeric');
            app.timeAdd.ValueChangedFcn = createCallbackFcn(app, @timeAddValueChanged);
            app.updatePlotBtt = uibutton(app.Panel3, 'push');
            app.updatePlotBtt.ButtonPushedFcn = createCallbackFcn(app, @updatePlotBttButtonPushed);
            app.updatePlotBtt.Text = 'Update plot';
            app.Save = uibutton(app.UIFigure, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed);
            app.Save.Text = 'Save';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'prefix tTH2';
            app.prfLab2 = uieditfield(app.UIFigure, 'text');
            app.prfLab2.Value = 'CAN_';
            app.LabelDropDown2 = uilabel(app.UIFigure);
            app.LabelDropDown2.Text = 'Save option';
            app.uidropdown = uidropdown(app.UIFigure);
            app.uidropdown.Items = {'save repeated signals with prefix', 'overwrite tTH2 on tTH1'};
            app.uidropdown.ItemsData = {'1', '2'};
            app.uidropdown.Value = '1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end