classdef main_multi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % UI Figure
        Panel                 matlab.ui.container.Panel          % load pannel
        tTHlongLoad           matlab.ui.control.Button           % Load tTH...
        tTHshortLoad          matlab.ui.control.Button           % Load tTH...
        Label                 matlab.ui.control.Label            % Label
        Label2                matlab.ui.control.Label            % Label
        Panel2                matlab.ui.container.Panel          % Resample
        LabelEditField        matlab.ui.control.Label            % dt
        resTime               matlab.ui.control.EditField        % 0.01
        resampleBtt           matlab.ui.control.Button           % Resample
        LampRes               matlab.ui.control.Lamp            
        Panel3                matlab.ui.container.Panel          % Synchron...
        sincroBtt             matlab.ui.control.Button           % Synchron...
        LabelNumericEditField matlab.ui.control.Label            % time
        timeAdd               matlab.ui.control.NumericEditField % [-Inf Inf]
        LampSincro            matlab.ui.control.Lamp            
        slowEvalBox           matlab.ui.control.CheckBox         % split ev...
        SaveSignalBtt         matlab.ui.control.StateButton      % None
        LabelDropDown         matlab.ui.control.Label            % long tTH...
        signalsList1          matlab.ui.control.DropDown         % Option 1...
        Label3                matlab.ui.control.Label            % short si...
        signalsList2          matlab.ui.control.DropDown         % Option 1...
        Label5                matlab.ui.control.Label            % Common s...
        cmChannelList         matlab.ui.control.DropDown         % Option 1...
        LabelDropDown3        matlab.ui.control.Label            % select A...
        signalShortDB         matlab.ui.control.DropDown         % Option 1...
        Save                  matlab.ui.control.Button           % Save
        UIAxes                matlab.ui.control.UIAxes           % Title
        Label4                matlab.ui.control.Label            % prefix tTH2
        prfLab2               matlab.ui.control.EditField        % CAN_
        LabelDropDown2        matlab.ui.control.Label            % Save option
        uidropdown            matlab.ui.control.DropDown         % Smart ma...
        Label6                matlab.ui.control.Label            % Status l...
        Label7                matlab.ui.control.Label           
        extImgBtt             matlab.ui.control.Button          
        updatePlotBtt         matlab.ui.control.Button          
    end

    
    properties (Access = public)
        tTH1;       % Struct acq 1
        len1        % lunghezza dei segnali di tTH1
        step1;      % step temporale dell'acq.1
        file1;      % nome dir e file dell'acq 1
        sinkName1;  % nome del segnale di riferimento sun cui calcolare la sincronizzazione
        
        tTH2;       % cell array of structure
        timeEnb     % matrice di tempo di allineamento e abilizatione al salvataggio
        len2;       % lunghezza dei segnali di tTH2
        step2;      % step temporale dell'acq.2
        file2;      % nome dir e file dell'acq 2
        sinkName2;  % nome del segnale di riferimento sun cui calcolare la sincronizzazione
        
        % sncFlag;
        idMin;      % id signalL in cui inizia signalS
        timeMin;    % tempo a cui è riferito idMin tTH1.time.v(idMin)
        
        extFig;     % external figure obj
        extSp;      % subplot estero di extFig
        error;      % errore calcolato dalla funzione di sincronizzazione
        opt;        %opzioni programma da setting.cfg
    end


    methods (Access = public)
        function statusProgram (app, message, color)
            if ischar(color)
                switch color
                    case 'r'
                        color = [1,0,0];
                    case 'g'
                        color = [0,1,0];
                    case 'o'
                        color = [255, 127, 39]/255;
                    otherwise
                        color = [0, 0, 1];
                end
            end
            
            app.Label6.Text = message;
            app.Label7.BackgroundColor = color;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.file1.dir = '.\';
            app.file2.dir = '.\';
            addpath('.\functions');
            app.extImgBtt.Icon = '.\ico\export.jpg';
            app.updatePlotBtt.Icon = '.\ico\update.png';
            %             app.sncFlag = 0;
            % creo un obj figure
            app.extFig = figure();
            delete(app.extFig);
            %Seve duplicates with prefix, marge signal with prefix
            %             [tb,btns] = axtoolbar(app.UIAxes,{'zoomin','zoomout','restoreview'});
            app.statusProgram('Ready', 'g');
            app.opt = extOpt('.\setting.cfg');
            assignin('base', 'app', app)
            
        end

        % tTHlongLoad button pushed function
        function tTHlongLoadButtonPushed(app)
            app.LampSincro.Color = [1,0,0];
            app.LampRes.Color = [1,0,0];
            delete(app.extFig);
            % caricamento traccia lunga
            app.statusProgram('Loading tTH1... wait', 'o');
            
            try
                [app.file1.name, app.file1.dir] = uigetfile(strcat(app.file1.dir,'*.mat'),'trace 1 (long trace)');
                app.UIFigure.Visible = 'off'; app.UIFigure.Visible = 'on'; % serve per far tornare in primo piano la figura principale
                [~,name,ext] = fileparts(app.file1.name);
                if ~strcmp(ext, '.mat')

                    fileDir = app.file1.dir;
                    fileName = app.file1.name;
                    resample = app.resTime.Value;
                    cutTime = '0';
                    outName = strcat(fileDir, name,'_tTH');
                    command = sprintf('"%s" %s %s %s %s %s',...
                        app.opt.FastMatCnv,...
                        fileDir, fileName, resample, cutTime, outName);
                                            s1 = 'Loading tTH1... wait';
                        s2 = sprintf('%d di %d attempted conversion:%s', 1, 1, fileName);
                        app.statusProgram({s1,s2}, 'o');
                        system(command)
                app.file1.name = strcat(name,'_tTH', '.mat');
                end
                app.tTH1  = load(strcat(app.file1.dir,app.file1.name)); app.tTH1 = app.tTH1.tTH; % carico acq.1
                app.len1  = length(app.tTH1.time.v);
                app.step1 = app.tTH1.time.v(2) -app.tTH1.time.v(1);
                %aggiorno la label con le informazioni utili
                s1 = sprintf('length : %d;', app.len1);
                s2 = sprintf('step   : %.3f s',app.step1);
                app.Label.Text = {app.file1.name; s1; s2} ;
                % aggiorno la list box dei nomi dei segnali
                app.signalsList1.Items = fieldnames(app.tTH1);
                % se ho già caricato tTH2 cerco i nomi che coincidono nelle due
                % acq e popolo la commom channel list.
                if iscell(app.tTH2)
                    names1 = app.signalsList1.Items;
                    names2 = app.signalsList2.Items;
                    listName = {};
                    app.error = {};
                    for i=1:length(names2)
%                         fprintf('%d) ',i)
                        name = names2{i};
                        
%     fprintf('name %s ->',  name)
                        if any(strcmp(names1, name))
                            listName{end+1} = name;
%                             fprintf(' added ')
                        end
%                         fprintf('Done \n')
                    end
%                     fprintf('sono arrivato qua 1\n')
                    app.cmChannelList.Items = listName;
%                     fprintf('sono arrivato qua 1\n')
                    for k = 1:length(app.tTH2)
                        app.error{k} = ones(size(app.tTH1.time.v))*inf;
                    end
%                     fprintf('sono arrivato qua 1\n')
% app.signalsList2ValueChanged();
                end
%                 app.
% app.signalsList1ValueChanged();
                app.statusProgram({'tTH1 loaded.', 'Ready'}, 'g');
            catch ME
                ME
                s1 = sprintf('Loading tTH1 error...');
                s2 = sprintf('error: %s', ME.identifier);
                app.statusProgram({s1, s2, 'Ready'}, 'g');
                app.Label.Text = {s1; s2} ;
            end
            
        end

        % tTHshortLoad button pushed function
        function tTHshortLoadButtonPushed(app)
            app.LampSincro.Color = [1,0,0];
            app.LampRes.Color = [1,0,0];
            delete(app.extFig);
            app.statusProgram('Loading tTH2... wait', 'o');
            try
                % caricamento l timehistory corta
                [app.file2.name, app.file2.dir] = uigetfile(strcat(app.file2.dir,'*.mat'),'trace 1 (long trace)', 'MultiSelect', 'on');
                app.UIFigure.Visible = 'off'; app.UIFigure.Visible = 'on'; % serve per far tornare in primo piano la figura principale
                if ischar(app.file2.name) % se è stato selezionato una sola acq cambia la struttura di  app.file2.name in cella
                    name = app.file2.name;
                    app.file2.name = {};
                    app.file2.name{1} = name;
                end
                
                for i=1:length(app.file2.name)
                    [~,name,ext] = fileparts(app.file2.name{i});
                    if ~strcmp(ext, '.mat')
                        fileDir = app.file2.dir;
                        fileName = app.file2.name{i};
                        resample = app.resTime.Value;
                        cutTime = '0';
                        outName = strcat(fileDir, name,'_tTH');
                        command = sprintf('"%s" %s %s %s %s %s',...
                            app.opt.FastMatCnv,...
                            fileDir, fileName, resample, cutTime, outName);
                        s1 = 'Loading tTH2... wait';
                        s2 = sprintf('%d di %d attempted conversion:%s', i, length(app.file2.name), fileName);
                        app.statusProgram({s1,s2}, 'o');
                        system(command)
                        app.file2.name{i} = strcat(name,'_tTH', '.mat');

                    end

                end
                % inizilizzo variabili
                
                app.error ={}; app.tTH2 = {}; app.len2 = []; app.step2 = [];
                
                % caricole acq selezionate
                for i=1:length(app.file2.name)
                    app.tTH2{i} = load(strcat(app.file2.dir,app.file2.name{i}));
                    % controllo che l'asse dei tempi sia lineare
                    if app.tTH2{i}.tTH.time.v(end)<app.tTH2{i}.tTH.time.v(end-1)
                        app.tTH2{i}.tTH.time.v(end) = app.tTH2{i}.tTH.time.v(end-1)+(app.tTH2{i}.tTH.time.v(2)-app.tTH2{i}.tTH.time.v(1));
                        s = sprintf('acq: %s time(end)< time(end-1), end value replaced with time(end-1)+ time(2)-time(1)', app.file2.name{i});
                        warndlg(s,'time space non linear');
                    end
                    % inizializzo la dopbox dei segnali dell'acq 2 ed elimito tutti i campi
                    % che non sono comuni tra le acq corte caricate
                    if i==1
                        app.signalsList2.Items = fieldnames(app.tTH2{1}.tTH);
                    end
                    names = fieldnames(app.tTH2{i}.tTH);
                    toDel = [];
                    for k=1:length(app.signalsList2.Items)
                        if ~any(strcmp(app.signalsList2.Items{k}, names))
                            toDel(end+1) = k;
                        end
                    end
                    app.signalsList2.Items(toDel) = [];
                    
                    % paramtri di visualizzazione
                    app.len2(i)  = length(app.tTH2{i}.tTH.time.v);
                    app.step2(i) = app.tTH2{i}.tTH.time.v(2) - app.tTH2{i}.tTH.time.v(1);
                    
                    % asse dei tempi di tutte le acq 2 azzerato (semplifica i calcoli dopo)
                    if app.tTH2{i}.tTH.time.v(1)<0
                        app.tTH2{i}.tTH.time.v = app.tTH2{i}.tTH.time.v-app.tTH2{i}.tTH.time.v(1);
                    end
                end
                
                % aggiorno la dropbox della lista dei segnali corti
                app.signalShortDB.Items = app.file2.name;
                app.signalShortDB.ItemsData = [1:length(app.signalShortDB.Items)];
                
                % variabile di raccolta dei tempi di sfasamento e abilitazione al save
                app.timeEnb = zeros(length(app.file2.name), 2);
                
                % aggiorno la label di summary dell'acq 2
                s1 = sprintf('length : %d;', median(app.len2));
                s2 = sprintf('step   : %.3f s',median(app.step2));
                
                if length(app.tTH2)>1
                    app.Label2.Text = {'MultiSelected traces'; s1; s2} ;
                    app.SaveSignalBtt.Enable ='on';
                    app.signalShortDB.Enable ='on';
                else
                    app.Label2.Text = {app.file2.name{1}; s1; s2};
                    app.timeEnb(1,2) = 1;  % abilito la prima acq ad essere salvata
                    app.SaveSignalBtt.Enable ='off';
                    app.signalShortDB.Enable ='off';
                end
                
%                 app.UIFigure.Visible = 'off'; app.UIFigure.Visible = 'on';
                
                % se ho già caricato tTH1 cerco i nomi che coincidono nelle due
                % acq e popolo la commom channel list.
                if isstruct(app.tTH1)
                    names1 = app.signalsList1.Items;
                    names2 = app.signalsList2.Items;
                    listName = {};
                    for i=1:length(names2)
                        name = names2{i};
                        if any(strcmp(names1, name))
                            listName{end+1} = name;
                        end
                    end
                    app.cmChannelList.Items = listName;
                    app.cmChannelList.Value = app.cmChannelList.Items{1};
                    app.cmChannelListValueChanged();
                    % inizializzo la matrice degli errori per eseguire i plot
                    app.error = {};
                    for k = 1:length(app.tTH2)
                        app.error{k} = ones(size(app.tTH1.time.v))*inf;
                    end
                    
                    % inizializzo la lista al primo valore;
                    app.signalShortDB.Value = 1;
                    app.signalShortDBValueChanged();
%                     app.signalsList1ValueChanged();
                end
%                 app.signalsList2ValueChanged();
                app.statusProgram({'tTH2 loaded.', 'Ready'}, 'g');
            catch ME
                s1 = sprintf('Loading tTH2 error...');
                s2 = sprintf('error: %s', ME.identifier);
                app.statusProgram({s1, s2, 'Ready'}, 'g');
                app.Label2.Text = {s1; s2} ;
            end
            %             app.LampRes.Color = [1,0,0];
        end

        % signalsList1 value changed function
        function signalsList1ValueChanged(app, event)
            % selezione del nome del segnale da sincronizzare
            value = app.signalsList1.Value;
            if ~any(strcmp(app.signalsList1.Items, value))
                app.signalsList1.Value = app.signalsList1.Items{1};
            end
            value = app.signalsList1.Value;
            app.sinkName1 = value;
            app.updatePlotBttButtonPushed();
            %             app.sncFlag = 0;
        end

        % signalsList2 value changed function
        function signalsList2ValueChanged(app, event)
            % selezione del nome del segnale da sincronizzare
            value = app.signalsList2.Value;
            if ~any(strcmp(app.signalsList2.Items, value))
                app.signalsList2.Value = app.signalsList2.Items{1};
            end
            value = app.signalsList2.Value;
            app.sinkName2 = value;
            app.updatePlotBttButtonPushed();
            %             app.sncFlag = 0;
        end

        % resampleBtt button pushed function
        function resampleBttButtonPushed(app)
            app.statusProgram('Resampling... wait', 'o');
            try
                totOp = 1 + length(app.tTH2);
                s1 = 'Resampling... wait';
                s2 = sprintf('%.1f %s', 0, 'resampling tTH1');
                app.statusProgram({s1, s2}, 'o');
                app.LampRes.Color = [1,0,0];
                interpolationError = {};
                step = str2num(app.resTime.Value);
                oldTime = app.tTH1.time.v;
                names = fieldnames(app.tTH1);
                
                timeSpace = oldTime(1):step: oldTime(end);
                timeSpace = timeSpace';
                try
                    for i=1:length(names)
                        app.tTH1.(names{i}).v = interp1(oldTime, app.tTH1.(names{i}).v, timeSpace);
                    end
                catch ME
                    s = sprintf('error on %s: %s', app.file2.name{k}, ME.identifier);
                    interpolationError{end+1} = s;
                end
                s1 = 'Resampling... wait';
                s2 = 'tTH1 done';
                s3 = sprintf('%.1f %s', 1/totOp*100, 'Starting resample tTH2');
                app.statusProgram({s1, s2, s3}, 'o');
                
                for k=1:length(app.tTH2)
                    try
                        oldTime = app.tTH2{k}.tTH.time.v;
                        names = fieldnames( app.tTH2{k}.tTH);
                        timeSpace = oldTime(1):step: oldTime(end);
                        timeSpace = timeSpace';
                        %             figure();
                        %     plot(app.tTH2.time.v, app.tTH2.EngineSpeed.v, '+k'); hold on;
                        for i=1:length(names)
                            app.tTH2{k}.tTH.(names{i}).v = interp1(oldTime, app.tTH2{k}.tTH.(names{i}).v, timeSpace);
                        end
                        app.len2(k)  = length(app.tTH2{k}.tTH.time.v);
                        app.step2(k) = app.tTH2{k}.tTH.time.v(2) -app.tTH2{k}.tTH.time.v(1);
                    catch ME
                        s = sprintf('error on %s: %s', app.file2.name{k}, ME.identifier);
                        interpolationError{end+1} = s;
                    end
                    s1 = 'Resampling... wait';
                    s2 = sprintf('tTH2: %s done!', app.file2.name{k});
                    s3 = sprintf('%.1f %s', (k+1)/totOp*100, 'Starting resample tTH2');
                    app.statusProgram({s1, s2, s3}, 'o');
                end
                if ~isempty(interpolationError)
                    interpolationError{end+1} = 'Please correct the track before continuing...';
                    %     disp(interpolationError)
                    warndlg(interpolationError,'error in resampling phase');
                else
                    %             plot(app.tTH2.time.v, app.tTH2.EngineSpeed.v, 'og');
                    app.len1  = length(app.tTH1.time.v);
                    app.step1 = app.tTH1.time.v(2) -app.tTH1.time.v(1);
                    %aggiorno la label con le informazioni utili
                    s1 = sprintf('length : %d;', app.len1);
                    s2 = sprintf('step   : %.3f s',app.step1);
                    app.Label.Text = {app.file1.name; s1; s2} ;
                    
                    %aggiorno la label con le informazioni util
                    s1 = sprintf('length : %d;', median(app.len2));
                    s2 = sprintf('step   : %.3f s',median(app.step2));
                    if length(app.tTH2)>1
                        app.Label2.Text = {'MultiSelected traces'; s1; s2} ;
                    else
                        app.Label2.Text = {app.file2.name{1}; s1; s2};
                    end
                    app.LampRes.Color = [0,1,0];
                    app.error = {};
                    for k = 1:length(app.tTH2)
                        app.error{k} = ones(size(app.tTH1.time.v))*inf;
                    end
                    s1 = 'Resampling... Done';
                    app.statusProgram(s1, 'g');
                end
            catch ME
                s1 = sprintf('Resample error...');
                s2 = sprintf('error: %s', ME.identifier);
                app.statusProgram({s1, s2, 'Ready'}, 'g');
            end
        end

        % sincroBtt button pushed function
        function sincroBttButtonPushed(app)
            app.LampSincro.Color = [1,0,0];
            s1 = 'Synchronization... wait';
            app.statusProgram(s1, 'o');
            if app.LampRes.Color == [1,0,0]
                app.statusProgram({'Synchronization... failed', 'Ready'}, 'g');
                warndlg('resample the acquisitions before synchronizing','resample error');
            else
                s2 = sprintf('%.1f : elaboration trace %s',0, app.file2.name{1});
                app.statusProgram({s1, s2}, 'o');
                
                for k = 1:length(app.tTH2)
                    try
                        totOp = length(app.tTH2);
                        assignin('base', 'app', app)
                        %                         disp(app.slowEvalBox.Value)
                        
                        if app.slowEvalBox.Value
                            [app.timeAdd.Value, error] = sncTimeSearch_1_3(app.tTH1.(app.sinkName1).v, app.tTH2{k}.tTH.(app.sinkName2).v,app.tTH1.time.v, app.tTH2{k}.tTH.time.v);
                        else
                            [app.timeAdd.Value, error] = sncTimeSearch(app.tTH1.(app.sinkName1).v, app.tTH2{k}.tTH.(app.sinkName2).v, app.tTH1.time.v);
                            [app.timeAdd.Value, error] = sncCheck(error, app.timeAdd.Value, app.tTH1.(app.sinkName1).v, app.tTH2{k}.tTH.(app.sinkName2).v, app.tTH1.time.v, app.tTH2{k}.tTH.time.v);
                        end
                        app.error{k} = error;
                        app.timeEnb(k,1) = app.timeAdd.Value;
                        s2 = sprintf('%.1f : elaboration trace %s',k/totOp*100, app.file2.name{k});
                        app.statusProgram({s1, s2}, 'o')
                    catch ME
                        s2 = sprintf('error on %s: %s', app.file2.name{k}, ME.identifier);
                        s3 = sprintf('the trace will be ignored');
                        app.statusProgram({s1, s2, s3}, 'r');
                    end
                end
                
                app.signalShortDB.Value = 1;
                app.signalShortDBValueChanged();
                app.updatePlotBttButtonPushed()
                app.LampSincro.Color = [0,1,0];
                s1 = 'Synchronization end.';
                app.statusProgram({s1, 'Ready'}, 'g');
            end
        end

        % updatePlotBtt button pushed function
        function updatePlotBttButtonPushed(app)
            id = app.signalShortDB.Value;
            %             app.LampSincro.Color = [1,0,0];
            if isvalid(app.extFig)
                app.extImgBttButtonPushed();
            end
            ax = app.UIAxes;
            xlabel(ax, 'Time [s]');
            ylabel(ax, strcat(app.sinkName1, ' [', app.tTH1.(app.sinkName1).u,']'));
            cla(ax, 'reset');
            hold(ax,'on');
            plot(ax, app.tTH1.time.v, app.tTH1.(app.sinkName1).v, 'DisplayName', 'tTH1 source');
            plot(ax, app.tTH2{id}.tTH.time.v, app.tTH2{id}.tTH.(app.sinkName2).v, 'DisplayName', 'tTH2 source');
            plot(ax, app.tTH2{id}.tTH.time.v + app.timeAdd.Value , app.tTH2{id}.tTH.(app.sinkName2).v, '--', 'DisplayName', 'tTH1 source');
            grid(ax, 'minor');
            %             legend(ax, {'tTH1 source', 'tTH2 source', 'tTH2 sinked'});
            %             app.LampSincro.Color = [0,1,0];
        end

        % Save button pushed function
        function SaveButtonPushed(app)
            risp = ceil(str2double(app.uidropdown.Value));
            s1 = 'Marging traces... wait';
            app.statusProgram(s1, 'o');
            tTH = app.tTH1;
            error_flag = 0;
                        if app.LampSincro.Color == [1,0,0];
                warndlg('unsynchronized tracks','Save allert');
            end
            try
                for k=1:length(app.tTH2)
                    timeSnc = app.timeEnb(k,1);
                    if app.timeEnb(k,2)
                        tTH = margeAndSave(risp, timeSnc, app.prfLab2.Value, tTH, app.tTH2{k}.tTH);
                    end
                    s2 = sprintf('%.1f : elaboration trace %s',k/length(app.tTH2)*100, app.file2.name{k});
                    app.statusProgram({s1, s2}, 'o')
                end
            catch ME
                error_flag =1;
                s2 = sprintf('error on marging file: %s', ME.identifier);
                app.statusProgram({'not Marged!!', s2, 'Ready'}, 'g')
            end
            if ~error_flag
                s1 = 'Saving trace... wait';
                app.statusProgram(s1, 'o');
                try
                    uisave('tTH', strcat(app.file1.name(1:end-4), '_',app.file2.name{1}(1:end-4)));
                    app.UIFigure.Visible = 'off'; app.UIFigure.Visible = 'on';
                    app.statusProgram({'Saved', 'Ready'}, 'g');
                catch ME
                    s2 = sprintf('error on saving file: %s', ME.identifier);
                    app.statusProgram({'not Saved!!', s2, 'Ready'}, 'g')
                end
            else
                s2 = sprintf('error on marging file: %s', ME.identifier);
                app.statusProgram({'not Marged!!', s2, 'Ready'}, 'g')
            end
            
            
        end

        % timeAdd value changed function
        function timeAddValueChanged(app)
            value = app.timeAdd.Value;
            id = app.signalShstatortDB.Value;
            app.timeEnb(id,1) = value;
            %             disp(app.timeEnb)
            app.updatePlotBttButtonPushed()
        end

        % zoomBtt value changed function
        function zoomBttValueChanged(app)
            
        end

        % extImgBtt button pushed function
        function extImgBttButtonPushed(app)
            id = app.signalShortDB.Value;
            %             app.LampSincro.Color = [1,0,0];
            if ~isvalid(app.extFig)
                app.extFig = figure();
                app.extSp = [subplot(3,1,[1:2]), subplot(3,1,3)];
            end
            app.extFig.Visible = 'off'; app.extFig.Visible = 'on';
            ax = app.extSp;
            
            cla(ax(1));
            cla(ax(1), 'reset');
            xlabel(ax(1), 'Time [s]');
            ylabel(ax(1), strcat(app.sinkName1, ' [', app.tTH1.(app.sinkName1).u,']'));
            hold(ax(1),'on');
            grid(ax(1), 'minor');
            
            plot(ax(1), app.tTH1.time.v, app.tTH1.(app.sinkName1).v, 'LineWidth',2, 'DisplayName', 'tTH1 source');
            plot(ax(1), app.tTH2{id}.tTH.time.v, app.tTH2{id}.tTH.(app.sinkName2).v, 'DisplayName', 'tTH2 source');
            plot(ax(1), app.tTH2{id}.tTH.time.v + app.timeAdd.Value, app.tTH2{id}.tTH.(app.sinkName2).v, '--', 'LineWidth',2, 'DisplayName', 'tTH2 Translated');
            
            cla(ax(2));
            cla(ax(2), 'reset');
            xlabel(ax(2), 'Time [s]');
            ylabel(ax(2), 'error');
            hold(ax(2),'on');
            grid(ax(2), 'minor');
            %             disp(app.error)
            %             disp(id)
            %             disp(length(app.error{id}))
            %
            plot(ax(2), app.tTH1.time.v, app.error{id}, 'LineWidth',2, 'DisplayName', 'error');
            %             app.LampSincro.Color = [0,1,0];
        end

        % cmChannelList value changed function
        function cmChannelListValueChanged(app, event)
            value = app.cmChannelList.Value;
            if ~any(strcmp(app.cmChannelList.Items, value))
                app.cmChannelList.Value = app.cmChannelList.Items{1};
            end
            value = app.cmChannelList.Value;
            app.signalsList1.Value = value;
            app.signalsList2.Value = value;
            app.sinkName1 = value;
            app.sinkName2 = value;
            app.updatePlotBttButtonPushed();
        end

        % signalShortDB value changed function
        function signalShortDBValueChanged(app, event)
            value = app.signalShortDB.Value;
            %             disp( app.timeEnb)
            app.timeAdd.Value = app.timeEnb(value,1);
            if app.timeEnb(value,2) == 1;
                app.SaveSignalBtt.Value = true;
                app.SaveSignalBtt.Text = 'Enabled';
                app.SaveSignalBtt.FontColor = [0,1,0];
            else
                app.SaveSignalBtt.Value = false;
                app.SaveSignalBtt.Text = 'Disabled';
                app.SaveSignalBtt.FontColor = [1,0,0];
                
            end
            app.updatePlotBttButtonPushed();
        end

        % SaveSignalBtt value changed function
        function SaveSignalBttValueChanged(app)
            value = app.SaveSignalBtt.Value;
            id = app.signalShortDB.Value;
            if value
                app.timeEnb(id,2) = 1;
            else
                app.timeEnb(id,2) = 0;
            end
            app.signalShortDBValueChanged();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'load pannel';
            app.Panel.Units = 'pixels';
            app.tTHlongLoad = uibutton(app.Panel, 'push');
            app.tTHlongLoad.ButtonPushedFcn = createCallbackFcn(app, @tTHlongLoadButtonPushed);
            app.tTHlongLoad.Text = 'Load tTH long';
            app.tTHshortLoad = uibutton(app.Panel, 'push');
            app.tTHshortLoad.ButtonPushedFcn = createCallbackFcn(app, @tTHshortLoadButtonPushed);
            app.tTHshortLoad.Text = 'Load tTH short';
            app.Label = uilabel(app.Panel);
            app.Label2 = uilabel(app.Panel);
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Resample';
            app.Panel2.Units = 'pixels';
            app.LabelEditField = uilabel(app.Panel2);
            app.LabelEditField.Text = 'dt';
            app.resTime = uieditfield(app.Panel2, 'text');
            app.resTime.Value = '0.01';
            app.resampleBtt = uibutton(app.Panel2, 'push');
            app.resampleBtt.ButtonPushedFcn = createCallbackFcn(app, @resampleBttButtonPushed);
            app.resampleBtt.Text = 'Resample';
            app.LampRes = uilamp(app.Panel2);
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Synchronization';
            app.Panel3.Units = 'pixels';
            app.sincroBtt = uibutton(app.Panel3, 'push');
            app.sincroBtt.ButtonPushedFcn = createCallbackFcn(app, @sincroBttButtonPushed);
            app.sincroBtt.Text = 'Synchronization';
            app.LabelNumericEditField = uilabel(app.Panel3);
            app.LabelNumericEditField.Text = 'time';
            app.timeAdd = uieditfield(app.Panel3, 'numeric');
            app.timeAdd.ValueChangedFcn = createCallbackFcn(app, @timeAddValueChanged);
            app.LampSincro = uilamp(app.Panel3);
            app.slowEvalBox = uicheckbox(app.Panel3);
            app.slowEvalBox.Text = 'split evaluation';
            app.SaveSignalBtt = uibutton(app.Panel3, 'state');
            app.SaveSignalBtt.ValueChangedFcn = createCallbackFcn(app, @SaveSignalBttValueChanged);
            app.SaveSignalBtt.Text = 'None';
            app.LabelDropDown = uilabel(app.Panel3);
            app.LabelDropDown.Text = 'long tTH signals List';
            app.signalsList1 = uidropdown(app.Panel3);
            app.signalsList1.Editable = 'on';
            app.signalsList1.ValueChangedFcn = createCallbackFcn(app, @signalsList1ValueChanged, true);
            app.Label3 = uilabel(app.Panel3);
            app.Label3.Text = 'short signals List';
            app.signalsList2 = uidropdown(app.Panel3);
            app.signalsList2.Editable = 'on';
            app.signalsList2.ValueChangedFcn = createCallbackFcn(app, @signalsList2ValueChanged, true);
            app.Label5 = uilabel(app.Panel3);
            app.Label5.Text = 'Common signals';
            app.cmChannelList = uidropdown(app.Panel3);
            app.cmChannelList.Editable = 'on';
            app.cmChannelList.ValueChangedFcn = createCallbackFcn(app, @cmChannelListValueChanged, true);
            app.LabelDropDown3 = uilabel(app.Panel3);
            app.LabelDropDown3.Text = 'select Acquisition short';
            app.signalShortDB = uidropdown(app.Panel3);
            app.signalShortDB.ValueChangedFcn = createCallbackFcn(app, @signalShortDBValueChanged, true);
            app.Save = uibutton(app.UIFigure, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed);
            app.Save.Text = 'Save';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'prefix tTH2';
            app.prfLab2 = uieditfield(app.UIFigure, 'text');
            app.prfLab2.Value = 'CAN_';
            app.LabelDropDown2 = uilabel(app.UIFigure);
            app.LabelDropDown2.Text = 'Save option';
            app.uidropdown = uidropdown(app.UIFigure);
            app.uidropdown.Items = {'Smart marge', 'Add prefix. if signal exist marge', 'No prefix, if signal exist ignore', 'No prefix, if signal exist overwrite'};
            app.uidropdown.ItemsData = {'1', '2', '3', '4'};
            app.uidropdown.Value = '1';
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = 'Status label';
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = '';
            app.extImgBtt = uibutton(app.UIFigure, 'push');
            app.extImgBtt.ButtonPushedFcn = createCallbackFcn(app, @extImgBttButtonPushed);
            app.extImgBtt.Text = '';
            app.updatePlotBtt = uibutton(app.UIFigure, 'push');
            app.updatePlotBtt.ButtonPushedFcn = createCallbackFcn(app, @updatePlotBttButtonPushed);
            app.updatePlotBtt.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main_multi()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end